classdef catch_map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        lbLabel                 matlab.ui.control.Label
        EditField_3             matlab.ui.control.NumericEditField
        ubEditField             matlab.ui.control.NumericEditField
        Label_3                 matlab.ui.control.Label
        importcolorbarButton    matlab.ui.control.Button
        importimageButton       matlab.ui.control.Button
        UIAxes9                 matlab.ui.control.UIAxes
        UIAxes5                 matlab.ui.control.UIAxes
        UIAxes8                 matlab.ui.control.UIAxes
        CenterPanel             matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        Label_7                 matlab.ui.control.Label
        timesSpinner            matlab.ui.control.Spinner
        IntervalSpinner         matlab.ui.control.Spinner
        Label_11                matlab.ui.control.Label
        DatavolumeEditField     matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        methodDropDown          matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        TolDisEditField         matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        sigmaSpinner            matlab.ui.control.Spinner
        sigmaLabel              matlab.ui.control.Label
        InterpolationButton     matlab.ui.control.Button
        MatchimageButton        matlab.ui.control.Button
        RemoveoutliersButton    matlab.ui.control.Button
        UIAxes7                 matlab.ui.control.UIAxes
        UIAxes6                 matlab.ui.control.UIAxes
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes3_2               matlab.ui.control.UIAxes
        RightPanel              matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        intervalSpinner         matlab.ui.control.Spinner
        Label_9                 matlab.ui.control.Label
        IntervalsamplingButton  matlab.ui.control.Button
        lengthofdataEditField   matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        nameEditField           matlab.ui.control.EditField
        Label_5                 matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        outputButton            matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        figure % Description
        color_bar % Description
        CB_W
        CB_L
        cat_data % Description
        T_T % Description
        LL % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: importimageButton
        function importimageButtonPushed(app, event)
            [file, path] =  uigetfile({"*.gif;*.png;*.jpg;*.tif;*.tiff","图像文件"},"multiselect","off");
            app.figure = imread(strcat(path,file));
            imshow(app.figure,'Parent',app.UIAxes5)
        end

        % Button pushed function: importcolorbarButton
        function importcolorbarButtonPushed(app, event)
            [file, path] =  uigetfile({"*.gif;*.png;*.jpg;*.tif;*.tiff","图像文件"},"multiselect","off");
            app.color_bar = imread(strcat(path,file));
            [app.CB_W,app.CB_L,~] = size(app.color_bar);

            imshow(app.color_bar,'Parent',app.UIAxes8);
            legth = size(app.color_bar,2);
            %             a = app.EditField_3.Value:(app.ubEditField.Value-app.EditField_3.Value)/(legth-1):app.ubEditField.Value;
            %             [xx,yy] = meshgrid(a,1:100);
            %             pcolor(app.UIAxes8,xx,yy,repmat(a,100,1));
            G   = app.color_bar(round(size(app.color_bar,1)/2),:,:);
            app.color_bar = zeros(size(G,2),3);
            app.color_bar(:,:) = G(:,:,:);
            %             colormap(app.UIAxes8,app.color_bar/255);
            %             imshow(app.color_bar,'Parent',app.UIAxes8);

            %             xlim(app.UIAxes8,[app.EditField_3.Value,app.ubEditField.Value]);
            plot3(app.UIAxes3,app.color_bar(:,1),app.color_bar(:,2),app.color_bar(:,3),LineWidth=3,Color=[0,0,1]);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            %                         xlim(app.UIAxes4,[app.EditField_3.Value,app.ubEditField.Value]);
        end

        % Value changed function: ubEditField
        function ubEditFieldValueChanged(app, event)
            value = app.ubEditField.Value;
            %                         xlim(app.UIAxes4,[app.EditField_3.Value,app.ubEditField.Value]);
        end

        % Button pushed function: RemoveoutliersButton
        function RemoveoutliersButtonPushed(app, event)
            A_R = app.color_bar(:,1);
            A_G = app.color_bar(:,2);
            A_B = app.color_bar(:,3);
            n = app.timesSpinner.Value;
            for i= 1:n
                %对R作差分
                DA_R = diff([A_R(2),A_R']);
                u_R = mean(DA_R);
                sigma_R = std(double(DA_R));
                lb_R = u_R-app.sigmaSpinner.Value*sigma_R;
                ub_R = u_R+app.sigmaSpinner.Value*sigma_R;
                ABV_R= (DA_R>ub_R|DA_R<lb_R);
                A_R(ABV_R == 1) =[];
                A_G(ABV_R == 1) = [];
                A_B(ABV_R == 1) = [];
                %对G作差分
                DA_G = diff([A_G(2),A_G']);
                u_G = mean(DA_G);
                sigma_G = std(double(DA_G));
                lb_G = u_G-app.sigmaSpinner.Value*sigma_G;
                ub_G = u_G+app.sigmaSpinner.Value*sigma_G;
                ABV_G= (DA_G>ub_G|DA_G<lb_G);
                A_R(ABV_G == 1) =[];
                A_G(ABV_G == 1) = [];
                A_B(ABV_G == 1) = [];
                %对B作差分
                DA_B = diff([A_B(2),A_B']);
                u_B = mean(DA_B);
                sigma_B = std(double(DA_B));
                lb_B = u_B-app.sigmaSpinner.Value*sigma_B;
                ub_B = u_B+app.sigmaSpinner.Value*sigma_B;
                ABV_B= (DA_B>ub_B|DA_B<lb_B);
                A_R(ABV_B == 1) =[];
                A_G(ABV_B == 1) = [];
                A_B(ABV_B == 1) = [];
            end
            app.color_bar = zeros(length(A_R),3);
            app.color_bar(:,1) = A_R;
            app.color_bar(:,2) = A_G;
            app.color_bar(:,3) = A_B;

            l = size(app.color_bar,1);
            n = 10;
            xi = linspace(1,l,n*l-1);
            xq = interp1(1:l,app.color_bar(:,1),xi);
            yq = interp1(1:l,app.color_bar(:,2),xi);
            zq = interp1(1:l,app.color_bar(:,3),xi);
            app.color_bar = zeros(length(xq),3);
            app.color_bar(:,1) = xq;
            app.color_bar(:,2) = yq;
            app.color_bar(:,3) = zq;
            app.color_bar = unique(app.color_bar,"rows",'stable');
            P = zeros(1,size(app.color_bar,1),3);
            P(1,:,:) = app.color_bar;
            P = repmat(P,app.CB_W*round(size(app.color_bar,1)/app.CB_L),1,1);
            plot3(app.UIAxes3_2,app.color_bar(:,1),app.color_bar(:,2),app.color_bar(:,3),LineWidth=3,Color=[0,0,1]);
            imshow(uint8(P),'Parent',app.UIAxes9);
        end

        % Button pushed function: MatchimageButton
        function MatchimageButtonPushed(app, event)
            lb_V = app.EditField_3.Value;
            ub_V = app.ubEditField.Value;
            V = lb_V:(ub_V-lb_V)/(size(app.color_bar,1)-1):ub_V;
            [m,n,~] = size(app.figure);
            T = -10000*ones(m,n);
            TT = zeros(m,n);
            d = uiprogressdlg(app.UIFigure,'Title','Calculating','Message','Please wait patiently.,1/2','Cancelable','on');
            for i = 1:m
                for j = 1:n
                    G = zeros(1,3);
                    G(:) = app.figure(i,j,:);
                    [Dist,ii] = min((sum((G-app.color_bar).^2,2).^0.5));
                    if Dist <app.TolDisEditField.Value
                        T(i,j) = V(ii);
                        TT(i,j) = ii;
                    end

                end
                d.Value = (i)/m;
            end

            T(T == -10000) = -1;

            BB = 255*ones(m,n,3);
            d = uiprogressdlg(app.UIFigure,'Title','Calculating','Message','Please wait patiently.,2/2','Cancelable','on');
            for i = 1:m
                for j = 1:n
                    if TT(i,j)~=0
                        BB(i,j,:) = app.color_bar(TT(i,j),:);
                    end

                end
                d.Value = i/m;
            end
            app.T_T = T;
            [xx0,~] = ind2sub(size(T),find(T~=-1));
            app.LL = length(xx0);
            app.DatavolumeEditField.Value = app.LL/app.IntervalSpinner.Value;
            imshow(uint8(BB),'Parent',app.UIAxes6);
        end

        % Value changed function: intervalSpinner
        function intervalSpinnerValueChanged(app, event)
            value = app.intervalSpinner.Value;
            app.lengthofdataEditField.Value = length(app.cat_data)/value;
        end

        % Button pushed function: IntervalsamplingButton
        function IntervalsamplingButtonPushed(app, event)
            answer = questdlg(strcat('Current data has',num2str(length(app.cat_data(1:app.intervalSpinner.Value:end,:))),'row, import to table now?'), ...
                'Dessert Menu', ...
                'Yes','No','Yes');
            % Handle response
            switch answer
                case 'Yes'
                    app.UITable.Data = [mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,1),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1),mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,2),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1),mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,3),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1)];
                case 'No'
            end
        end

        % Button pushed function: outputButton
        function outputButtonPushed(app, event)
            TEXT = app.nameEditField.Value;
            a =1;
            try
                path = uigetdir;
                %                 a =0;
            catch
                a = 1;
            end
            try
                disp(strcat(path,'/',TEXT,'.txt'))
                writecell(app.UITable.Data,strcat(path,'/',TEXT,'.txt'));
                a =0 ;
            catch
                a = 1;
            end
            %             catch
            %                 a =1;
            %             end
            if a ==0
                f = msgbox(strcat('Saved successfully.',"Target",path,TEXT));
            else
                f = msgbox('Save failed', '  ','Error','error');
            end

        end

        % Button pushed function: InterpolationButton
        function InterpolationButtonPushed(app, event)
            T = app.T_T;
            [xx,yy] = meshgrid(1:size(T,1),1:size(T,2));
            % xx0 = xx(T~=-1);
            [xx0,yy0] = ind2sub(size(T),find(T~=-1));

            % yy0 = yy(T~=-1);
            zz0 = T(T~=-1);
            xx0 =  xx0(1:app.IntervalSpinner.Value:end);
            yy0 = yy0(1:app.IntervalSpinner.Value:end);
            zz0 = zz0(1:app.IntervalSpinner.Value:end);
            d = uiprogressdlg(app.UIFigure,'Title','Interpolating','Message','Please wait patiently.','Cancelable','on',"Indeterminate","on");

            if app.methodDropDown.Value == "linear"
                Z=griddata(xx0,yy0,zz0,xx,yy,'linear');
            elseif app.methodDropDown.Value == "nearest"
                Z=griddata(xx0,yy0,zz0,xx,yy,'nearest');
            elseif app.methodDropDown.Value == "natural"
                Z=griddata(xx0,yy0,zz0,xx,yy,'natural');
            elseif app.methodDropDown.Value == "cubic"
                Z=griddata(xx0,yy0,zz0,xx,yy,'cubic');
            elseif app.methodDropDown.Value == "v4"
                Z=griddata(xx0,yy0,zz0,xx,yy,'v4');
            end
            Z(isnan(Z)) = 0;
            h = pcolor(app.UIAxes7,yy,fliplr(xx),Z);
            xx = fliplr(xx);
            set(h,"edgecolor",'none');
            colorbar(app.UIAxes7)
            colormap(app.UIAxes7,(app.color_bar)./255)
            z00 = Z(Z~=isnan(Z));
            x00 = yy(Z~=isnan(Z));
            y00 = xx(Z~=isnan(Z));
            app.cat_data = [x00,y00,z00];
            app.lengthofdataEditField.Value = length(z00);

            answer = questdlg(strcat('Current data has',num2str(length(z00)),'row, import all into the table?'), ...
                'Dessert Menu', ...
                'Import now','Import later','Import later');
            % Handle response
            switch answer
                case 'Import now'
                app.UITable.Data = [mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,1),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1),mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,2),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1),mat2cell(app.cat_data(1:app.intervalSpinner.Value:end,3),ones(length(app.cat_data(1:app.intervalSpinner.Value:end,1)),1),1)];
                case 'Import later'
            end

        end

        % Callback function
        function EditField_7ValueChanged(app, event)

        end

        % Value changed function: IntervalSpinner
        function IntervalSpinnerValueChanged(app, event)
            value = app.IntervalSpinner.Value;
            app.DatavolumeEditField.Value = app.LL/value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {557, 557, 557};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {557, 557};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {279, '1x', 217};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.UIAxes8 = uiaxes(app.GridLayout2);
            title(app.UIAxes8, 'Original Color Bar')
            app.UIAxes8.YTick = [];
            app.UIAxes5 = uiaxes(app.GridLayout2);
            title(app.UIAxes5, 'original image')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes9 = uiaxes(app.GridLayout2);
            title(app.UIAxes9, 'Calculated color bar')
            app.UIAxes9.YTick = [];
            app.importimageButton = uibutton(app.GridLayout2, 'push');
            app.importimageButton.ButtonPushedFcn = createCallbackFcn(app, @importimageButtonPushed, true);
            app.importimageButton.Text = 'import image';
            app.importcolorbarButton = uibutton(app.GridLayout2, 'push');
            app.importcolorbarButton.ButtonPushedFcn = createCallbackFcn(app, @importcolorbarButtonPushed, true);
            app.importcolorbarButton.Text = 'import color bar';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = 'ub';
            app.ubEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ubEditField.ValueChangedFcn = createCallbackFcn(app, @ubEditFieldValueChanged, true);
            app.ubEditField.Value = 20;
            app.EditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.lbLabel = uilabel(app.GridLayout2);
            app.lbLabel.Text = 'lb';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.CenterPanel);
            app.UIAxes3_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes3_2, 'The color path of calculated color bar')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'The color path of original color bar')
            app.UIAxes6 = uiaxes(app.GridLayout3);
            title(app.UIAxes6, 'Extract color data')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout3);
            title(app.UIAxes7, 'Processed data')
            zlabel(app.UIAxes7, 'Z')
            app.RemoveoutliersButton = uibutton(app.GridLayout3, 'push');
            app.RemoveoutliersButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveoutliersButtonPushed, true);
            app.RemoveoutliersButton.Text = 'Remove outliers';
            app.MatchimageButton = uibutton(app.GridLayout3, 'push');
            app.MatchimageButton.ButtonPushedFcn = createCallbackFcn(app, @MatchimageButtonPushed, true);
            app.MatchimageButton.Text = 'Match image';
            app.InterpolationButton = uibutton(app.GridLayout3, 'push');
            app.InterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationButtonPushed, true);
            app.InterpolationButton.Text = 'Interpolation';
            app.sigmaLabel = uilabel(app.GridLayout3);
            app.sigmaLabel.Text = 'sigma';
            app.sigmaSpinner = uispinner(app.GridLayout3);
            app.sigmaSpinner.Limits = [1 Inf];
            app.sigmaSpinner.Value = 4;
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'Tol. Dis. ';
            app.TolDisEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TolDisEditField.Value = 10;
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = 'method';
            app.methodDropDown = uidropdown(app.GridLayout3);
            app.methodDropDown.Items = {'linear', 'nearest', 'natural', 'cubic', 'v4'};
            app.methodDropDown.Value = 'natural';
            app.Label_10 = uilabel(app.GridLayout3);
            app.Label_10.Text = 'Data volume';
            app.DatavolumeEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DatavolumeEditField.Editable = 'off';
            app.Label_11 = uilabel(app.GridLayout3);
            app.Label_11.Text = 'Interval ';
            app.IntervalSpinner = uispinner(app.GridLayout3);
            app.IntervalSpinner.Limits = [1 Inf];
            app.IntervalSpinner.ValueChangedFcn = createCallbackFcn(app, @IntervalSpinnerValueChanged, true);
            app.IntervalSpinner.Value = 120;
            app.timesSpinner = uispinner(app.GridLayout3);
            app.timesSpinner.Limits = [1 Inf];
            app.timesSpinner.Value = 2;
            app.Label_7 = uilabel(app.GridLayout3);
            app.Label_7.Text = 'times';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.outputButton = uibutton(app.GridLayout4, 'push');
            app.outputButton.ButtonPushedFcn = createCallbackFcn(app, @outputButtonPushed, true);
            app.outputButton.Text = 'output';
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'X'; 'Y'; 'Z'};
            app.UITable.RowName = {};
            app.Label_5 = uilabel(app.GridLayout4);
            app.Label_5.Text = 'name';
            app.nameEditField = uieditfield(app.GridLayout4, 'text');
            app.nameEditField.Value = 'data';
            app.Label_8 = uilabel(app.GridLayout4);
            app.Label_8.Text = 'length of data';
            app.lengthofdataEditField = uieditfield(app.GridLayout4, 'numeric');
            app.lengthofdataEditField.Editable = 'off';
            app.IntervalsamplingButton = uibutton(app.GridLayout4, 'push');
            app.IntervalsamplingButton.ButtonPushedFcn = createCallbackFcn(app, @IntervalsamplingButtonPushed, true);
            app.IntervalsamplingButton.Text = {'Interval '; 'sampling'};
            app.Label_9 = uilabel(app.GridLayout4);
            app.Label_9.Text = 'interval';
            app.intervalSpinner = uispinner(app.GridLayout4);
            app.intervalSpinner.Limits = [1 Inf];
            app.intervalSpinner.ValueChangedFcn = createCallbackFcn(app, @intervalSpinnerValueChanged, true);
            app.intervalSpinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = catch_map

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end